classdef final_project_part2 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                      matlab.ui.Figure
        GridLayout                    matlab.ui.container.GridLayout
        LeftPanel                     matlab.ui.container.Panel
        SaveasCSVButton               matlab.ui.control.Button
        ModifySignalButton            matlab.ui.control.Button
        PlotButton                    matlab.ui.control.Button
        GenerateSignalButton          matlab.ui.control.Button
        NumberofBreakPointsEditField  matlab.ui.control.NumericEditField
        NumberofBreakPointsEditFieldLabel  matlab.ui.control.Label
        EndTimeEditField              matlab.ui.control.NumericEditField
        EndTimeEditFieldLabel         matlab.ui.control.Label
        StartTimeEditField            matlab.ui.control.NumericEditField
        StartTimeEditFieldLabel       matlab.ui.control.Label
        SamplingFrequencyEditField    matlab.ui.control.NumericEditField
        SamplingFrequencyEditFieldLabel  matlab.ui.control.Label
        RightPanel                    matlab.ui.container.Panel
        UIAxes                        matlab.ui.control.UIAxes
    end

    % Properties that correspond to apps with auto-reflow
    properties (Access = private)
        onePanelWidth = 576;
    end

    
    properties (Access = private)
        Samples
        SignalValues
        BreakPoints
        FilePath
 % Description
    end
    
    methods (Access = private)
        
        function signal_values = generateSignals(~, sample_points)
            signal_types = {'DC Signal', 'Ramp Signal', 'Polynomial', 'Exponential', 'Sinusoidal'};
            [selection, ok] = listdlg('PromptString', 'Select a signal type:', ...
                                       'SelectionMode', 'single', ...
                                       'ListString', signal_types);
            if ~ok
                signal_values = zeros(size(sample_points));
                return;
            end

            switch signal_types{selection}
                case 'DC Signal'
                    amplitude = inputdlg('Enter amplitude:', 'DC Signal');
                    signal_values = str2double(amplitude{1}) * ones(size(sample_points));
                case 'Ramp Signal'
                    slope = inputdlg('Enter slope:', 'Ramp Signal');
                    intercept = inputdlg('Enter intercept:', 'Ramp Signal');
                    signal_values = str2double(slope{1}) * sample_points + str2double(intercept{1});
                case 'Polynomial'
                    amplitude = inputdlg('Enter amplitude:', 'Polynomial');
                    power = inputdlg('Enter power:', 'Polynomial');
                    intercept = inputdlg('Enter intercept:', 'Polynomial');
                    signal_values = str2double(amplitude{1}) * (sample_points .^ str2double(power{1})) + str2double(intercept{1});
                case 'Exponential'
                    amplitude = inputdlg('Enter amplitude:', 'Exponential');
                    exponent = inputdlg('Enter exponent:', 'Exponential');
                    signal_values = str2double(amplitude{1}) * exp(str2double(exponent{1}) * sample_points);
                case 'Sinusoidal'
                    amplitude = inputdlg('Enter amplitude:', 'Sinusoidal');
                    frequency = inputdlg('Enter frequency:', 'Sinusoidal');
                    phase = inputdlg('Enter phase (in radians):', 'Sinusoidal');
                    signal_values = str2double(amplitude{1}) * sin(2 * pi * str2double(frequency{1}) * sample_points + str2double(phase{1}));
                otherwise
                    signal_values = zeros(size(sample_points));
            end
        end
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: GenerateSignalButton
        function GenerateSignalButtonPushed(app, event)
            fs = app.SamplingFrequencyEditField.Value;
            start_time = app.StartTimeEditField.Value;
            end_time = app.EndTimeEditField.Value;
            num_breakpoints = app.NumberofBreakPointsEditField.Value;
            
            if isempty(fs) || fs <= 0
                uialert(app.UIFigure, 'Enter a valid sampling frequency (positive value).', 'Input Error');
                return;
            end
            if isempty(start_time) || isempty(end_time) || end_time <= start_time
                uialert(app.UIFigure, 'End time must be greater than start time.', 'Input Error');
                return;
            end
            if isempty(num_breakpoints) || num_breakpoints < 0
                uialert(app.UIFigure, 'Number of breakpoints must be a non-negative integer.', 'Input Error');
                return;
            end

            % Collect break points
            app.BreakPoints = start_time;
            %if num_breakpoints == 0
            for i = 1:num_breakpoints
                prompt = sprintf('Enter break point %d: ', i);
                bp = str2double(inputdlg(prompt, 'Input Break Point'));
                while isempty(bp) || bp <= start_time || bp >= end_time
                    uialert(app.UIFigure, 'Break point must be between start and end times.', 'Input Error');
                    bp = str2double(inputdlg(prompt, 'Input Break Point'));
                end
                app.BreakPoints(end + 1) = bp;
            %end
            end 
            app.BreakPoints = sort([app.BreakPoints, end_time]);
            disp(app.BreakPoints)

            % Generate samples
            app.Samples = start_time:(1/fs):end_time;
            app.SignalValues = zeros(size(app.Samples));

            % Generate signals for each region
            for i = 1:length(app.BreakPoints) - 1
                region_start = app.BreakPoints(i);
                region_end = app.BreakPoints(i + 1);
                region_samples = app.Samples(app.Samples >= region_start & app.Samples < region_end);
                region_signal = app.generateSignals(region_samples);
                app.SignalValues(app.Samples >= region_start & app.Samples < region_end) = region_signal;
            end

            uialert(app.UIFigure, 'Signal generated successfully!', 'Success');
        end

        % Button pushed function: PlotButton
        function PlotButtonPushed(app, event)
             if isempty(app.Samples) || isempty(app.SignalValues)
                uialert(app.UIFigure, 'No signal generated to plot.', 'Error');
                return;
            end
            plot(app.UIAxes, app.Samples, app.SignalValues, 'LineWidth', 1.5);
            xlabel(app.UIAxes, 'Time');
            ylabel(app.UIAxes, 'Amplitude');
            title(app.UIAxes, 'Generated Signal');
            grid(app.UIAxes, 'on');

        end

        % Button pushed function: ModifySignalButton
        function ModifySignalButtonPushed(app, event)
            if isempty(app.SignalValues)
                uialert(app.UIFigure, 'No signal to modify. Generate a signal first.', 'Error');
                return;
            end

            % Prompt user for modification type
            modification_types = {'Amplitude Scaling', 'Time Shift', 'Expand', 'Compress', 'Cancel'};
            [selection, ok] = listdlg('PromptString', 'Select a modification type:', ...
                                       'SelectionMode', 'single', ...
                                       'ListString', modification_types);
            if ~ok || strcmp(modification_types{selection}, 'Cancel')
                return;
            end

            % Perform the selected modification
            switch modification_types{selection}
                case 'Amplitude Scaling'
                    scale = inputdlg('Enter amplitude scaling factor:', 'Amplitude Scaling');
                    app.SignalValues = app.SignalValues * str2double(scale{1});
                case 'Time Shift'
                    shift = inputdlg('Enter time shift value:', 'Time Shift');
                    app.Samples = app.Samples + str2double(shift{1});
                case 'Expand'
                    expand = inputdlg('Enter expansion factor:', 'Expand');
                    app.Samples = app.Samples * str2double(expand{1});
                case 'Compress'
                    compress = inputdlg('Enter compression factor:', 'Compress');
                    app.Samples = app.Samples / str2double(compress{1});
            end

            % Update plot after modification
            app.PlotButtonPushed();

        end

        % Button pushed function: SaveasCSVButton
        function SaveasCSVButtonPushed(app, event)
            if isempty(app.Samples) || isempty(app.SignalValues)
                uialert(app.UIFigure, 'No signal data to save. Please generate a signal first.', 'Error');
                return;
            end
        
            % Open save dialog for CSV file
            [file, path] = uiputfile('*.csv', 'Save Signal As');
            if isequal(file, 0)
                % User canceled the save dialog
                return;
            end
        
            % Save data to CSV
            app.FilePath = fullfile(path, file);
            signal_data = table(app.Samples(:), app.SignalValues(:), 'VariableNames', {'Time', 'Amplitude'});
            writetable(signal_data, app.FilePath);
        
            % Notify the user
            uialert(app.UIFigure, sprintf('Signal saved successfully to %s', app.FilePath), 'Success');

        end

        % Changes arrangement of the app based on UIFigure width
        function updateAppLayout(app, event)
            currentFigureWidth = app.UIFigure.Position(3);
            if(currentFigureWidth <= app.onePanelWidth)
                % Change to a 2x1 grid
                app.GridLayout.RowHeight = {480, 480};
                app.GridLayout.ColumnWidth = {'1x'};
                app.RightPanel.Layout.Row = 2;
                app.RightPanel.Layout.Column = 1;
            else
                % Change to a 1x2 grid
                app.GridLayout.RowHeight = {'1x'};
                app.GridLayout.ColumnWidth = {220, '1x'};
                app.RightPanel.Layout.Row = 1;
                app.RightPanel.Layout.Column = 2;
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.SizeChangedFcn = createCallbackFcn(app, @updateAppLayout, true);
            app.GridLayout = uigridlayout(app.UIFigure);
            app.LeftPanel = uipanel(app.GridLayout);
            app.SamplingFrequencyEditFieldLabel = uilabel(app.LeftPanel);
            app.SamplingFrequencyEditFieldLabel.Text = 'Sampling Frequency';
            app.SamplingFrequencyEditField = uieditfield(app.LeftPanel, 'numeric');
            app.StartTimeEditFieldLabel = uilabel(app.LeftPanel);
            app.StartTimeEditFieldLabel.Text = 'Start Time';
            app.StartTimeEditField = uieditfield(app.LeftPanel, 'numeric');
            app.EndTimeEditFieldLabel = uilabel(app.LeftPanel);
            app.EndTimeEditFieldLabel.Text = 'End Time';
            app.EndTimeEditField = uieditfield(app.LeftPanel, 'numeric');
            app.NumberofBreakPointsEditFieldLabel = uilabel(app.LeftPanel);
            app.NumberofBreakPointsEditFieldLabel.Text = 'Number of Break Points';
            app.NumberofBreakPointsEditField = uieditfield(app.LeftPanel, 'numeric');
            app.GenerateSignalButton = uibutton(app.LeftPanel, 'push');
            app.GenerateSignalButton.ButtonPushedFcn = createCallbackFcn(app, @GenerateSignalButtonPushed, true);
            app.GenerateSignalButton.Text = 'Generate Signal';
            app.PlotButton = uibutton(app.LeftPanel, 'push');
            app.PlotButton.ButtonPushedFcn = createCallbackFcn(app, @PlotButtonPushed, true);
            app.PlotButton.Text = 'Plot';
            app.ModifySignalButton = uibutton(app.LeftPanel, 'push');
            app.ModifySignalButton.ButtonPushedFcn = createCallbackFcn(app, @ModifySignalButtonPushed, true);
            app.ModifySignalButton.Text = 'Modify Signal';
            app.SaveasCSVButton = uibutton(app.LeftPanel, 'push');
            app.SaveasCSVButton.ButtonPushedFcn = createCallbackFcn(app, @SaveasCSVButtonPushed, true);
            app.SaveasCSVButton.Text = 'Save as CSV';
            app.RightPanel = uipanel(app.GridLayout);
            app.UIAxes = uiaxes(app.RightPanel);
            title(app.UIAxes, 'Title')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            zlabel(app.UIAxes, 'Z')
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = final_project_part2

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end